classdef Giaodien_mophong < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        HiunngBERTab              matlab.ui.container.Tab
        ExportButton              matlab.ui.control.Button
        RunButton                 matlab.ui.control.Button
        InputPanel                matlab.ui.container.Panel
        MaxEbNoEditField          matlab.ui.control.NumericEditField
        EbN0Label                 matlab.ui.control.Label
        NumBitsEditField          matlab.ui.control.NumericEditField
        SbitLabel                 matlab.ui.control.Label
        ModulationDropDown        matlab.ui.control.DropDown
        LachnphngthcLabel         matlab.ui.control.Label
        BERAxes                   matlab.ui.control.UIAxes
        ConstellationRealtimeTab  matlab.ui.container.Tab
        EbNoSlider                matlab.ui.control.Slider
        McnhiuEbN0dBLabel         matlab.ui.control.Label
        RxAxes                    matlab.ui.control.UIAxes
        TxAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        EbNoData % Lưu mảng giá trị trục X
    BERData  % Lưu mảng giá trị trục Y (Mô phỏng)
    ModType  % Lưu tên loại điều chế để đặt tên file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Code dán vào bên trong hàm RunButtonPushed(app, event)

% 1. Lấy thông số từ giao diện (GUI)
modType = app.ModulationDropDown.Value;
numBits = app.NumBitsEditField.Value;
maxEbNo = app.MaxEbNoEditField.Value;

% Xác định bậc điều chế M dựa trên DropDown
switch modType
    case 'BPSK'
        M = 2;
    case 'QPSK'
        M = 4;
    case '16-QAM'
        M = 16;
end

k = log2(M);
sps = 4; rollOff = 0.25; span = 6;

% 2. Khởi tạo nguồn và lọc định hình xung
dataIn = randi([0 1], numBits, 1);
dataSym = bit2int(dataIn, k);

% Dùng pskmod cho BPSK/QPSK và qammod cho 16-QAM
if strcmp(modType, '16-QAM')
    txSig = qammod(dataSym, M);
else
    txSig = pskmod(dataSym, M, pi/M); % PSK có thêm pha xoay chuẩn
end

rrcFilter = rcosdesign(rollOff, span, sps, 'sqrt');
txSigFiltered = upfirdn(txSig, rrcFilter, sps);

% 3. Vòng lặp quét Eb/N0
EbNoVec = 0:2:maxEbNo;
berSim = zeros(size(EbNoVec));

% Đổi màu nút để báo hiệu đang chạy
app.RunButton.Text = 'Đang chạy...';
app.RunButton.Enable = 'off';
drawnow; % Cập nhật giao diện ngay lập tức

for i = 1:length(EbNoVec)
    snr = EbNoVec(i) + 10*log10(k) - 10*log10(sps);
    rxSig = awgn(txSigFiltered, snr, 'measured');
    rxSigFiltered = upfirdn(rxSig, rrcFilter, 1, sps);
    rxSym = rxSigFiltered(span+1 : end-span);
    
    if strcmp(modType, '16-QAM')
        dataOutSym = qamdemod(rxSym, M);
    else
        dataOutSym = pskdemod(rxSym, M, pi/M);
    end
    
    dataOut = int2bit(dataOutSym, k);
    [~, berSim(i)] = biterr(dataIn, dataOut);
end

% 4. Tính BER lý thuyết
if strcmp(modType, '16-QAM')
    berTheory = berawgn(EbNoVec, 'qam', M);
else
    berTheory = berawgn(EbNoVec, 'psk', M, 'nondiff');
end

% 5. Vẽ đồ thị lên giao diện (UIAxes)
% Lưu ý: Hàm semilogy phải truyền app.UIFigure vào làm tham số đầu tiên
plot(app.BERAxes, EbNoVec, berTheory, 'b-', 'LineWidth', 2);
hold(app.BERAxes, 'on');

% Vẽ điểm thực nghiệm
plot(app.BERAxes, EbNoVec, berSim, 'ro', 'MarkerSize', 8, 'LineWidth', 1.5);
hold(app.BERAxes, 'off');

% --- BƯỚC QUAN TRỌNG: Chuyển trục Y sang thang logarit ---
app.BERAxes.YScale = 'log';

% Bật lưới và thiết lập nhãn
grid(app.BERAxes, 'on');
app.BERAxes.Title.String = ['Hiệu năng BER của ', modType];
app.BERAxes.XLabel.String = 'Eb/N0 (dB)';
app.BERAxes.YLabel.String = 'Tỷ lệ lỗi bit (BER)';
legend(app.BERAxes, {'Lý thuyết', 'Mô phỏng'}); % Thêm ngoặc nhọn {} cho legend

% Khôi phục nút bấm
app.RunButton.Text = 'Chạy Mô Phỏng';
app.RunButton.Enable = 'on';
app.EbNoData = EbNoVec;
app.BERData = berSim;
app.ModType = modType;
        end

        % Value changed function: EbNoSlider
        function EbNoSliderValueChanged(app, event)
        currentEbNo = app.EbNoSlider.Value;

% Lấy loại điều chế từ Tab 1 (để đồng bộ hệ thống)
modType = app.ModulationDropDown.Value;

switch modType
    case 'BPSK'
        M = 2;
    case 'QPSK'
        M = 4;
    case '16-QAM'
        M = 16;
end
k = log2(M);

% 2. Tạo nhanh một khối dữ liệu nhỏ (2000 symbol là đủ để vẽ cho nhẹ máy)
numSyms = 2000;
dataIn = randi([0 M-1], numSyms, 1);

% Điều chế
if strcmp(modType, '16-QAM')
    txSig = qammod(dataIn, M);
else
    txSig = pskmod(dataIn, M, pi/M);
end

% 3. Thêm nhiễu trực tiếp (Để trực quan, ta bỏ qua phần bộ lọc ở tab này)
% Tính SNR cho tín hiệu gốc (chưa upsample)
snr = currentEbNo + 10*log10(k);
rxSig = awgn(txSig, snr, 'measured');

% 4. Vẽ lên giao diện (Sử dụng hàm scatter thay vì scatterplot)
% Xóa dữ liệu cũ trên trục
cla(app.TxAxes);
cla(app.RxAxes);

% Vẽ tín hiệu phát (Lý tưởng - Màu xanh)
scatter(app.TxAxes, real(txSig), imag(txSig), 15, 'b', 'filled');
app.TxAxes.XGrid = 'on'; app.TxAxes.YGrid = 'on';
app.TxAxes.XLim = [-4 4]; app.TxAxes.YLim = [-4 4]; % Giữ khung hình cố định

% Vẽ tín hiệu thu (Có nhiễu - Màu đỏ)
scatter(app.RxAxes, real(rxSig), imag(rxSig), 15, 'r', 'filled');
app.RxAxes.XGrid = 'on'; app.RxAxes.YGrid = 'on';
app.RxAxes.XLim = [-4 4]; app.RxAxes.YLim = [-4 4];
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Kiểm tra xem đã có dữ liệu để xuất chưa
if isempty(app.EbNoData) || isempty(app.BERData)
    uialert(app.UIFigure, 'Vui lòng chạy mô phỏng trước khi xuất dữ liệu!', 'Lỗi xuất dữ liệu', 'Icon', 'warning');
    return; % Dừng lại không xuất
end

% 1. XUẤT ĐỒ THỊ RA ẢNH (PNG độ phân giải cao)
% Hàm exportgraphics là cách hiện đại nhất để xuất hình ảnh từ App Designer
imageName = sprintf('BER_Performance_%s.png', app.ModType);
exportgraphics(app.BERAxes, imageName, 'Resolution', 300);

% 2. XUẤT DỮ LIỆU RA FILE EXCEL / CSV
% Ghép 2 mảng EbNo và BER thành 2 cột
exportMatrix = [app.EbNoData', app.BERData'];
csvName = sprintf('Data_BER_%s.csv', app.ModType);

% Ghi ra file CSV (có thể mở bằng Excel)
writematrix(exportMatrix, csvName);

% 3. THÔNG BÁO THÀNH CÔNG
successMsg = sprintf('Đã xuất thành công 2 file:\n- %s\n- %s\nvào thư mục hiện tại của MATLAB.', imageName, csvName);
uialert(app.UIFigure, successMsg, 'Xuất Thành Công', 'Icon', 'success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HiunngBERTab = uitab(app.TabGroup);
            app.HiunngBERTab.Title = 'Hiệu năng BER';
            app.BERAxes = uiaxes(app.HiunngBERTab);
            title(app.BERAxes, 'Title')
            xlabel(app.BERAxes, 'X')
            ylabel(app.BERAxes, 'Y')
            zlabel(app.BERAxes, 'Z')
            app.InputPanel = uipanel(app.HiunngBERTab);
            app.InputPanel.Title = 'Input:';
            app.LachnphngthcLabel = uilabel(app.InputPanel);
            app.LachnphngthcLabel.Text = {'Chọn phương'; ' thức:'};
            app.ModulationDropDown = uidropdown(app.InputPanel);
            app.ModulationDropDown.Items = {'BPSK', 'QPSK', '16-QAM'};
            app.ModulationDropDown.Value = 'BPSK';
            app.SbitLabel = uilabel(app.InputPanel);
            app.SbitLabel.Text = 'Số bit:';
            app.NumBitsEditField = uieditfield(app.InputPanel, 'numeric');
            app.NumBitsEditField.Value = 100000;
            app.EbN0Label = uilabel(app.InputPanel);
            app.EbN0Label.Text = 'Eb/N0:';
            app.MaxEbNoEditField = uieditfield(app.InputPanel, 'numeric');
            app.MaxEbNoEditField.Value = 14;
            app.RunButton = uibutton(app.HiunngBERTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Chạy mô phỏng';
            app.ExportButton = uibutton(app.HiunngBERTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'ExportButton';
            app.ConstellationRealtimeTab = uitab(app.TabGroup);
            app.ConstellationRealtimeTab.Title = 'Constellation Real-time';
            app.TxAxes = uiaxes(app.ConstellationRealtimeTab);
            title(app.TxAxes, 'Tín hiệu phát lý tưởng')
            xlabel(app.TxAxes, 'X')
            ylabel(app.TxAxes, 'Y')
            zlabel(app.TxAxes, 'Z')
            app.RxAxes = uiaxes(app.ConstellationRealtimeTab);
            title(app.RxAxes, 'Tín hiệu Thu qua nhiễu AWGN')
            xlabel(app.RxAxes, 'X')
            ylabel(app.RxAxes, 'Y')
            zlabel(app.RxAxes, 'Z')
            app.McnhiuEbN0dBLabel = uilabel(app.ConstellationRealtimeTab);
            app.McnhiuEbN0dBLabel.Text = {'Mức nhiễu '; 'Eb/N0 (dB)'};
            app.EbNoSlider = uislider(app.ConstellationRealtimeTab);
            app.EbNoSlider.Limits = [0 20];
            app.EbNoSlider.ValueChangedFcn = createCallbackFcn(app, @EbNoSliderValueChanged, true);
            app.EbNoSlider.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Giaodien_mophong

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end